classdef BER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptCohBERcurveUIFigure         matlab.ui.Figure
        BackButton                     matlab.ui.control.Button
        StartSimulationButton          matlab.ui.control.Button
        EqualizationLabel              matlab.ui.control.Label
        LinkModelLabel                 matlab.ui.control.Label
        SimulationLabel                matlab.ui.control.Label
        DampingFactorEditField         matlab.ui.control.NumericEditField
        DampingFactorEditFieldLabel    matlab.ui.control.Label
        N2EditField                    matlab.ui.control.NumericEditField
        N2EditFieldLabel               matlab.ui.control.Label
        N1EditField                    matlab.ui.control.NumericEditField
        N1EditFieldLabel               matlab.ui.control.Label
        mu2EditField                   matlab.ui.control.NumericEditField
        mu2Label                       matlab.ui.control.Label
        mu1EditField                   matlab.ui.control.NumericEditField
        mu1Label                       matlab.ui.control.Label
        NumperoftapsEditField          matlab.ui.control.NumericEditField
        NumperoftapsEditFieldLabel     matlab.ui.control.Label
        PolrotationradsEditField       matlab.ui.control.NumericEditField
        PolrotationradsEditFieldLabel  matlab.ui.control.Label
        delta_nuHzEditField            matlab.ui.control.NumericEditField
        delta_nuHzEditFieldLabel       matlab.ui.control.Label
        NumberofpointsEditField        matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel   matlab.ui.control.Label
        OSNRenddBEditField             matlab.ui.control.NumericEditField
        OSNRenddBLabel                 matlab.ui.control.Label
        OSNRstartdBEditField           matlab.ui.control.NumericEditField
        OSNRstartdBEditFieldLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        Rs
        r% Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r, Rs)
            if r==1
                app.NumperoftapsEditField.Value = 8;
                app.DampingFactorEditField.Value = 150;
            elseif r==2
                app.NumperoftapsEditField.Value = 11;
                app.DampingFactorEditField.Value = 30;
            end
            app.Rs = Rs;
            app.r = r;
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            if app.OSNRstartdBEditField.Value>app.OSNRenddBEditField.Value
                f = msgbox("'OSNR start' must be smaller than 'OSNR end'");
            else
                app.OptCohBERcurveUIFigure.Visible = 'off';
                f = msgbox(["Simulation is running";"Please wait"]);
                GUI_main_BER(app.r,app.Rs, app.OSNRstartdBEditField.Value, app.OSNRenddBEditField.Value, app.NumberofpointsEditField.Value, ...
                    app.delta_nuHzEditField.Value, app.PolrotationradsEditField.Value, app.NumperoftapsEditField.Value, app.mu1EditField.Value, ...
                    app.mu2EditField.Value, app.N1EditField.Value, app.N2EditField.Value, app.DampingFactorEditField.Value);
                close(f);
                app.OptCohBERcurveUIFigure.Visible = 'on';
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            mainApp = main;
            app.OptCohBERcurveUIFigure.Visible = 'off';
            mainApp.OptCohUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptCohBERcurveUIFigure = uifigure('Visible', 'off');
            app.OptCohBERcurveUIFigure.Name = 'OptCoh - BER curve';
            app.OSNRstartdBEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.OSNRstartdBEditFieldLabel.Text = 'OSNR start [dB]';
            app.OSNRstartdBEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.OSNRstartdBEditField.Value = 5;
            app.OSNRenddBLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.OSNRenddBLabel.Text = 'OSNR end [dB]';
            app.OSNRenddBEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.OSNRenddBEditField.Value = 20;
            app.NumberofpointsEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.NumberofpointsEditField.Limits = [1 Inf];
            app.NumberofpointsEditField.Value = 10;
            app.delta_nuHzEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.delta_nuHzEditFieldLabel.Text = 'delta_nu [Hz]';
            app.delta_nuHzEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.delta_nuHzEditField.Limits = [0 Inf];
            app.delta_nuHzEditField.ValueDisplayFormat = '%1.2e';
            app.delta_nuHzEditField.Value = 50000;
            app.PolrotationradsEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.PolrotationradsEditFieldLabel.Text = 'Pol. rotation [rad/s]';
            app.PolrotationradsEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.PolrotationradsEditField.Limits = [0 Inf];
            app.PolrotationradsEditField.ValueDisplayFormat = '%1.2e';
            app.PolrotationradsEditField.Value = 1000;
            app.NumperoftapsEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.NumperoftapsEditFieldLabel.Text = 'Numper of taps';
            app.NumperoftapsEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.NumperoftapsEditField.Limits = [1 Inf];
            app.NumperoftapsEditField.Value = 11;
            app.mu1Label = uilabel(app.OptCohBERcurveUIFigure);
            app.mu1Label.Text = 'mu1';
            app.mu1EditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.mu1EditField.Limits = [0 Inf];
            app.mu1EditField.ValueDisplayFormat = '%1.2e';
            app.mu1EditField.Value = 0.008;
            app.mu2Label = uilabel(app.OptCohBERcurveUIFigure);
            app.mu2Label.Text = 'mu2';
            app.mu2EditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.mu2EditField.Limits = [0 Inf];
            app.mu2EditField.ValueDisplayFormat = '%1.2e';
            app.mu2EditField.Value = 0.0008;
            app.N1EditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.N1EditField.Limits = [0 Inf];
            app.N1EditField.ValueDisplayFormat = '%1.2e';
            app.N1EditField.Value = 1000;
            app.N2EditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.N2EditField.Limits = [0 Inf];
            app.N2EditField.ValueDisplayFormat = '%1.2e';
            app.N2EditField.Value = 300000;
            app.DampingFactorEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.DampingFactorEditFieldLabel.Text = 'Damping Factor';
            app.DampingFactorEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.DampingFactorEditField.Limits = [0 Inf];
            app.DampingFactorEditField.Value = 150;
            app.SimulationLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.SimulationLabel.Text = 'Simulation';
            app.LinkModelLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.LinkModelLabel.Text = 'Link Model';
            app.EqualizationLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.EqualizationLabel.Text = 'Equalization';
            app.StartSimulationButton = uibutton(app.OptCohBERcurveUIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.BackButton = uibutton(app.OptCohBERcurveUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.OptCohBERcurveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BER(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptCohBERcurveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptCohBERcurveUIFigure)
        end
    end
end