classdef Scatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptCohScatterplotUIFigure      matlab.ui.Figure
        BackButton                     matlab.ui.control.Button
        OSNRdBEditField                matlab.ui.control.NumericEditField
        OSNRdBEditFieldLabel           matlab.ui.control.Label
        StartSimulationButton          matlab.ui.control.Button
        EqualizationLabel              matlab.ui.control.Label
        LinkModelLabel                 matlab.ui.control.Label
        DampingFactorEditField         matlab.ui.control.NumericEditField
        DampingFactorEditFieldLabel    matlab.ui.control.Label
        N2EditField                    matlab.ui.control.NumericEditField
        N2EditFieldLabel               matlab.ui.control.Label
        N1EditField                    matlab.ui.control.NumericEditField
        N1EditFieldLabel               matlab.ui.control.Label
        mu2EditField                   matlab.ui.control.NumericEditField
        mu2EditFieldLabel              matlab.ui.control.Label
        mu1EditField                   matlab.ui.control.NumericEditField
        mu1EditFieldLabel              matlab.ui.control.Label
        NumperoftapsEditField          matlab.ui.control.NumericEditField
        NumperoftapsEditFieldLabel     matlab.ui.control.Label
        PolrotationradsEditField       matlab.ui.control.NumericEditField
        PolrotationradsEditFieldLabel  matlab.ui.control.Label
        delta_nuHzEditField            matlab.ui.control.NumericEditField
        delta_nuHzEditFieldLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        Rs
        r% Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r, Rs)
            if r==1
                app.NumperoftapsEditField.Value = 8;
                app.DampingFactorEditField.Value = 150;
            elseif r==2
                app.NumperoftapsEditField.Value = 11;
                app.DampingFactorEditField.Value = 30;
            end
            app.Rs = Rs;
            app.r = r;
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.OptCohScatterplotUIFigure.Visible = 'off';
            f = msgbox(["Simulation is running";"Please wait"]);
            GUI_main_scatterplot(app.r,app.Rs, app.OSNRdBEditField.Value, ...
                app.delta_nuHzEditField.Value, app.PolrotationradsEditField.Value, app.NumperoftapsEditField.Value, app.mu1EditField.Value, ...
                app.mu1EditField.Value, app.N1EditField.Value, app.N2EditField.Value, app.DampingFactorEditField.Value);
            % function GUI_main_scatterplot(r,Rs, OSNR_dB, delta_nu, rad_sec, EQ_N_tap, EQ_mu, EQ_mu2, EQ_N1, EQ_N2, CarSync_DampFac)
            close(f);
            app.OptCohScatterplotUIFigure.Visible = 'on';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            mainApp = main;
            app.OptCohScatterplotUIFigure.Visible = 'off';
            mainApp.OptCohUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptCohScatterplotUIFigure = uifigure('Visible', 'off');
            app.OptCohScatterplotUIFigure.Name = 'OptCoh - Scatterplot';
            app.delta_nuHzEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.delta_nuHzEditFieldLabel.Text = 'delta_nu [Hz]';
            app.delta_nuHzEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.delta_nuHzEditField.Limits = [0 Inf];
            app.delta_nuHzEditField.ValueDisplayFormat = '%1.2e';
            app.delta_nuHzEditField.Value = 50000;
            app.PolrotationradsEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.PolrotationradsEditFieldLabel.Text = 'Pol. rotation [rad/s]';
            app.PolrotationradsEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.PolrotationradsEditField.Limits = [0 Inf];
            app.PolrotationradsEditField.ValueDisplayFormat = '%1.2e';
            app.PolrotationradsEditField.Value = 1000;
            app.NumperoftapsEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.NumperoftapsEditFieldLabel.Text = 'Numper of taps';
            app.NumperoftapsEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.NumperoftapsEditField.Limits = [1 Inf];
            app.NumperoftapsEditField.Value = 11;
            app.mu1EditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.mu1EditFieldLabel.Text = 'mu 1';
            app.mu1EditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.mu1EditField.Limits = [0 Inf];
            app.mu1EditField.ValueDisplayFormat = '%1.2e';
            app.mu1EditField.Value = 0.008;
            app.mu2EditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.mu2EditFieldLabel.Text = 'mu 2';
            app.mu2EditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.mu2EditField.Limits = [0 Inf];
            app.mu2EditField.ValueDisplayFormat = '%1.2e';
            app.mu2EditField.Value = 0.0008;
            app.N1EditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.N1EditField.Limits = [0 Inf];
            app.N1EditField.ValueDisplayFormat = '%1.2e';
            app.N1EditField.Value = 1000;
            app.N2EditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.N2EditField.Limits = [0 Inf];
            app.N2EditField.ValueDisplayFormat = '%1.2e';
            app.N2EditField.Value = 300000;
            app.DampingFactorEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.DampingFactorEditFieldLabel.Text = 'Damping Factor';
            app.DampingFactorEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.DampingFactorEditField.Limits = [0 Inf];
            app.DampingFactorEditField.Value = 150;
            app.LinkModelLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.LinkModelLabel.Text = 'Link Model';
            app.EqualizationLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.EqualizationLabel.Text = 'Equalization';
            app.StartSimulationButton = uibutton(app.OptCohScatterplotUIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.OSNRdBEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.OSNRdBEditFieldLabel.Text = 'OSNR [dB]';
            app.OSNRdBEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.OSNRdBEditField.Limits = [0 Inf];
            app.OSNRdBEditField.Value = 10;
            app.BackButton = uibutton(app.OptCohScatterplotUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.OptCohScatterplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scatter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptCohScatterplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptCohScatterplotUIFigure)
        end
    end
end